classdef unified_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        RecordTab                    matlab.ui.container.Tab
        Record_DButton               matlab.ui.control.Button
        Record_UButton               matlab.ui.control.Button
        Record_TIMEDIVKnob           matlab.ui.control.DiscreteKnob
        TIMEDIVKnobLabel             matlab.ui.control.Label
        Record_VOLTSDIVKnob          matlab.ui.control.DiscreteKnob
        VOLTSDIVKnobLabel            matlab.ui.control.Label
        Record_RButton               matlab.ui.control.Button
        Record_LButton               matlab.ui.control.Button
        Record_FreqHzEditField       matlab.ui.control.NumericEditField
        FreqHzEditField_2Label       matlab.ui.control.Label
        Record_tmsEditField          matlab.ui.control.NumericEditField
        tmsEditFieldLabel            matlab.ui.control.Label
        Record_AvgmVEditField        matlab.ui.control.NumericEditField
        AvgmVEditFieldLabel          matlab.ui.control.Label
        Record_PPAmpmVEditField      matlab.ui.control.NumericEditField
        PPAmpmVEditFieldLabel        matlab.ui.control.Label
        Record_MinmVEditField        matlab.ui.control.NumericEditField
        MinmVEditFieldLabel          matlab.ui.control.Label
        Record_MaxmVEditField        matlab.ui.control.NumericEditField
        MaxmVEditFieldLabel          matlab.ui.control.Label
        Record_RecordLamp            matlab.ui.control.Lamp
        Record_SAVEButton            matlab.ui.control.Button
        Record_RECORDButton          matlab.ui.control.Button
        Record_AUTOSETButton         matlab.ui.control.Button
        Record_RUNSTOPButton         matlab.ui.control.Button
        Record_Plot                  matlab.ui.control.UIAxes
        UploaderTab                  matlab.ui.container.Tab
        StdmsLabel                   matlab.ui.control.Label
        AvgmsLabel                   matlab.ui.control.Label
        MinmsLabel                   matlab.ui.control.Label
        MaxmsLabel                   matlab.ui.control.Label
        Playback_MinmVEditField_2    matlab.ui.control.NumericEditField
        Playback_AvgmVEditField_2    matlab.ui.control.NumericEditField
        Playback_PPAmpmVEditField_2  matlab.ui.control.NumericEditField
        Playback_MaxmVEditField_2    matlab.ui.control.NumericEditField
        IntervalLabel                matlab.ui.control.Label
        PPmVLabel                    matlab.ui.control.Label
        AvgmVLabel                   matlab.ui.control.Label
        MinmVLabel                   matlab.ui.control.Label
        MaxmVLabel                   matlab.ui.control.Label
        UL_AvgmVEditField            matlab.ui.control.NumericEditField
        UL_MaxmVEditField            matlab.ui.control.NumericEditField
        UL_MinmVEditField            matlab.ui.control.NumericEditField
        UL_PPAmpmVEditField          matlab.ui.control.NumericEditField
        AmplitudeLabel               matlab.ui.control.Label
        DurationLabel                matlab.ui.control.Label
        FsHzLabel                    matlab.ui.control.Label
        UL_MaxmVEditField_2          matlab.ui.control.NumericEditField
        SamplingRateLabel            matlab.ui.control.Label
        vmVLabel_2                   matlab.ui.control.Label
        tmsLabel_2                   matlab.ui.control.Label
        UL_del_tmsEditField          matlab.ui.control.NumericEditField
        UL_del_vmVEditField          matlab.ui.control.NumericEditField
        UL_FlushButton               matlab.ui.control.Button
        UL_LoopSwitch                matlab.ui.control.Switch
        LoopSwitchLabel              matlab.ui.control.Label
        UL_SearchButton              matlab.ui.control.Button
        UL_UploadButton              matlab.ui.control.Button
        UL_FileEditField             matlab.ui.control.EditField
        FileEditFieldLabel           matlab.ui.control.Label
        UL_Plot                      matlab.ui.control.UIAxes
        PortsDropDown                matlab.ui.control.DropDown
        PortsDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        device % The Seeeduino device

        default_port_message='Select a port'
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Record
        Record_DispVoltBuffer = [] % Buffer for storing data values
        Record_DispTimestampBuffer = [] % Buffer for storing timestamp values

        saveVoltBuffer = []
        saveTimestampBuffer=[]
        
        
        Record_DataTimer % Timer for data acquisition
        Record_plotTimer % Timer for updating the plot
        showLength = 1000 % Number of points to show on the plot

        % RUN/STOP Btn Click
        isRunning logical = true % Flag to updating the plot

        % RECORD Btn
        isRecording logical = false
        
        % Click management on the plot
        record_plot_clicked logical = false % To track the number of clicks
        vLine1 % To store the handle to the first vertical line
        vLine2 % To store the handle to the second vertical line
        shadedArea % To store the handle to the shaded area patch

        % Zoom/Shift on the plot
        Record_tTickSize
        Record_vTickSize
        
        Record_tOffsetByTick
        Record_vOffsetByTick
        
        Record_tTickNumDisp
        Record_vTickNumDisp

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Uploader
        UL_vBuf = [] 
        UL_tBuf = []

        UL_st_vline
        UL_st_x
        UL_end_vline
        UL_shaded_area

        UL_clicked logical = false

    end
    
    methods (Access = private)
        
        function acquireData(app)
            if ~isempty(app.device)
                while app.device.NumBytesAvailable > 0
                    dataLine = readline(app.device);
                    dataParts = strsplit(dataLine, ',');
                    if length(dataParts) ~= 2
                        error('dataline is wrong, not [timestamps, volts]')
                    end

                    millis = str2double(dataParts{1});
                    numericValue = str2double(dataParts{2});
                    if ~isnan(numericValue) && ~isnan(millis)
                        elapsedMillisec = millis;
                        if app.isRunning
                            app.Record_DispVoltBuffer = [app.Record_DispVoltBuffer, numericValue];
                            app.Record_DispTimestampBuffer = [app.Record_DispTimestampBuffer, elapsedMillisec];
                        end

                        if app.isRecording
                            app.saveVoltBuffer=[app.saveVoltBuffer numericValue];
                            app.saveTimestampBuffer=[app.saveTimestampBuffer elapsedMillisec];
                        end
                        
                        if length(app.Record_DispVoltBuffer) > app.showLength
                            app.Record_DispVoltBuffer = app.Record_DispVoltBuffer(end-app.showLength+1:end);
                            app.Record_DispTimestampBuffer = app.Record_DispTimestampBuffer(end-app.showLength+1:end);
                        end
                    end
                end
            end
        end
        
        function updatePlot(app)
            if(app.isRunning && ~isempty(app.Record_DispTimestampBuffer))
                app.M_plot();

                % Edit the features
                app.Record_tmsEditField.Value=min(app.Record_DispVoltBuffer);
                app.Record_MaxmVEditField.Value=max(app.Record_DispVoltBuffer);
                app.Record_PPAmpmVEditField.Value=app.Record_MaxmVEditField.Value-app.Record_tmsEditField.Value;
                app.Record_AvgmVEditField.Value=mean(app.Record_DispVoltBuffer);
                app.Record_FreqHzEditField.Value=app.DomFreq(app.Record_DispTimestampBuffer,app.Record_DispVoltBuffer);
            end
        end

        
        function resetMarkOnRecordPlot(app)
            if isgraphics(app.vLine1, 'line')
                delete(app.vLine1);
            end
            if isgraphics(app.vLine2, 'line')
                delete(app.vLine2);
            end
            if isgraphics(app.shadedArea, 'patch')
                delete(app.shadedArea);
            end
        end


        function freq = DomFreq(app,timestamps,volts)

            % Ensure timestamps are uniformly spaced
            dt = mean(diff(timestamps)); % Average time interval between samples
            Fs = 1000/dt; % Sampling frequency
            
            % Remove the DC component by subtracting the mean
            volts = volts - mean(volts);
            
            % Compute the Fast Fourier Transform (FFT)
            Y = fft(volts);
            
            % Get the number of points
            n = length(Y);
            Y = Y(1:floor(n/2)+1); % Use only the first half of Y (positive frequencies)
            
            % Compute the frequency values corresponding to Y
            f = (0:floor(n/2)) * Fs / n;
            
            % Find the magnitude of the FFT, which represents the amplitude of each frequency component
            P = abs(Y);
            
            % Find the index of the dominant frequency (excluding the DC component)
            [~, maxIdx] = max(P(2:end)); % Start from index 2 to exclude the DC component (0 Hz)
            maxIdx = maxIdx + 1; % Adjust index because we started from the second element
            
            % Get the dominant frequency based on the index
            freq = f(maxIdx);

        end
        
        function M_plot(app)
            % Plot the data
                plot(app.Record_Plot, app.Record_DispTimestampBuffer, app.Record_DispVoltBuffer);
                
                % Ensure there's data to work with
                if ~isempty(app.Record_DispTimestampBuffer)
                    % Calculate the right limit of the x-axis based on the latest timestamp
                    lastTimestamp = app.Record_DispTimestampBuffer(end);
                    
                    % Calculate the right limit of the x-axis
                    rtlim = lastTimestamp + app.Record_tOffsetByTick * app.Record_tTickSize;
                    
                    % Calculate the left limit of the x-axis based on the number of ticks to display and tick size
                    ltlim = rtlim - app.Record_tTickNumDisp * app.Record_tTickSize;
                    
                    % Set the x-axis limits
                    xlim(app.Record_Plot, [ltlim, rtlim]);
                end
                
                % Update the plot
                drawnow;
        end

        % function UL_AdjustWin(app)
        %     % Plot the data
        % 
        % 
        %     % Variables to hold the indices of data within the visible range
        %     visibleIndices = [];
        % 
        %     % Ensure there's data to work with
        %     if ~isempty(app.UL_tBuf)
        %         % Calculate the right limit of the x-axis based on the latest timestamp
        %         app.UL_ct=round(app.UL_ct/app.UL_tTickSize)*app.UL_tTickSize;
        % 
        %         lastT = app.UL_tBuf(end);
        %         % Calculate the right limit of the x-axis
        %         rtlim = lastT - app.UL_ct +app.UL_tHalfWinByTick*app.UL_tTickSize;
        % 
        %         % Calculate the left limit of the x-axis based on the number of ticks to display and tick size
        %         ltlim = rtlim - 2 * app.UL_tHalfWinByTick * app.UL_tTickSize;
        % 
        % 
        %         % Set the x-axis limits
        %         xlim(app.UL_Plot, [ltlim rtlim]);
        % 
        %         % Find indices of the data within the visible range
        %         visibleIndices = find(app.UL_tBuf >= ltlim & app.UL_tBuf <= rtlim);
        %     end
        % 
        %     if ~isempty(app.UL_vBuf)
        %         app.UL_cv=round(app.UL_cv/app.UL_vTickSize);
        % 
        %         uvlim=app.UL_cv + app.UL_vHalfWinByTick *app.UL_vTickSize;
        %         dvlim=app.UL_cv - app.UL_vHalfWinByTick *app.UL_vTickSize;
        %         ylim(app.UL_Plot, [dvlim uvlim]);
        %     end
        % 
        %     % Update the plot
        %     drawnow;
        % 
        %     % % Only update statistics if there are visible indices
        %     % if ~isempty(visibleIndices)
        %     %     % Calculate statistics for visible data
        %     %     visibleVoltages = app.UL_vBuf(visibleIndices);
        %     %     app.UL_MinmVEditField.Value = min(visibleVoltages);
        %     %     app.UL_MaxmVEditField.Value = max(visibleVoltages);
        %     %     app.UL_PPAmpmVEditField.Value = app.UL_MaxmVEditField.Value - app.UL_MinmVEditField.Value;
        %     %     app.UL_AvgmVEditField.Value = mean(visibleVoltages);
        %     % 
        %     %     % Assuming app.DomFreq calculates dominant frequency
        %     %     app.Playback_FreqHzEditField.Value = app.DomFreq(app.UL_tBuf(visibleIndices), visibleVoltages);
        %     % else
        %     %     % Handle case where no data is visible
        %     %     app.UL_MinmVEditField.Value = NaN;
        %     %     app.UL_MaxmVEditField.Value = NaN;
        %     %     app.UL_PPAmpmVEditField.Value = NaN;
        %     %     app.UL_AvgmVEditField.Value = NaN;
        %     %     app.Playback_FreqHzEditField.Value = NaN;
        %     % end
        % end
        

        function saveCSV(app, timestamps, volts)
            % Open a file save dialog allowing the user to specify a CSV file
            [file, path] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save as');
            if isequal(file, 0) || isequal(path, 0)
                disp('User clicked Cancel.');
            else
                % Construct the full path where the file will be saved
                fullPath = fullfile(path, file);
                disp(['User selected ', fullPath, ' for saving.']);
                
                % Create a table from the timestamps and volts arrays
                T = table(timestamps(:), volts(:), 'VariableNames', {'Timestamps', 'Volts'});
                
                % Write the table to a CSV file
                writetable(T, fullPath);
                disp(['Data saved to ', fullPath]);
            end
        end

        function [time, volt] = readCSV(app, csv_path)
            % Define keywords to identify the time and voltage columns
            TIME_INDICATORS = {'time', 'sec', 'stamp'};
            VOLT_INDICATORS = {'volt', 'amp', 'meas'};
            
            % Read the CSV file. This assumes the file has a header row.
            opts = detectImportOptions(csv_path);
            data = readtable(csv_path, opts);
            
            % Initialize output variables
            time = [];
            volt = [];
            
            % Loop over all column names to find matches for time and voltage
            for i = 1:length(opts.VariableNames)
                varName = lower(opts.VariableNames{i});  % Convert to lowercase to ensure case-insensitivity
                % Check if the column name contains any of the time indicators
                if any(contains(varName, TIME_INDICATORS))
                    time = data{:, i};
                % Check if the column name contains any of the voltage indicators
                elseif any(contains(varName, VOLT_INDICATORS))
                    volt = -data{:, i};
                end
            end
            
            % Check if time or volt columns were found
            if isempty(time)
                error('Time column not found in the CSV file.');
            end
            if isempty(volt)
                error('Voltage column not found in the CSV file.');
            end
        end

        
        function UL_delComp(app)
           if isfield(app, 'UL_st_vline') && isvalid(app.UL_st_vline)
                delete(app.UL_st_vline);
            end
            if isfield(app, 'UL_end_vline') && isvalid(app.UL_end_vline)
                delete(app.UL_end_vline);
            end
            if isfield(app, 'UL_shaded_area') && isvalid(app.UL_shaded_area)
                delete(app.UL_shaded_area);
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            availablePorts = serialportlist("available");
            app.PortsDropDown.Items = [app.default_port_message, availablePorts];
            app.PortsDropDown.Value = app.default_port_message;
        
            % Initialize timers but don't start them yet
            app.Record_DataTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.01, ...
                                  'TimerFcn', @(src, event)acquireData(app));
            app.Record_plotTimer = timer('ExecutionMode', 'fixedRate', 'Period', 2, ...
                                  'TimerFcn', @(src, event)updatePlot(app));

            % Plot tick init (Record)
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          
            if ~isempty(app.Record_DataTimer)
                stop(app.Record_DataTimer);
                delete(app.Record_DataTimer);
            end
            if ~isempty(app.Record_plotTimer)
                stop(app.Record_plotTimer);
                delete(app.Record_plotTimer);
            end
            if ~isempty(app.device)
                delete(app.device);
            end
            delete(app.UIFigure);

        end

        % Value changed function: PortsDropDown
        function PortsDropDownValueChanged(app, event)

            value = app.PortsDropDown.Value;
            if ~strcmp(value, app.default_port_message)
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                try
                    app.device = serialport(value, 9600);
                    configureTerminator(app.device, "CR/LF");
                    % Start timers when a valid port is selected and device is configured
                    start(app.Record_DataTimer);
                    start(app.Record_plotTimer);
                catch exception
                    uialert(app.UIFigure, exception.message, 'Serial Port Error');
                    app.PortsDropDown.Value = app.default_port_message;
                end
            else
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                % Stop timers if the default message is selected
                stop(app.Record_DataTimer);
                stop(app.Record_plotTimer);
            end

        end

        % Button down function: Record_Plot
        function Record_PlotButtonDown(app, event)
            % Get the current point clicked by the user
            currentPoint = app.Record_Plot.CurrentPoint;
            xClick = currentPoint(1,1); % X-coordinate of the click
            
            if ~app.isRunning % Can mark only when the plot updating pauses
                if ~app.record_plot_clicked % First click
                    app.resetMarkOnRecordPlot();
                    app.vLine1 = line(app.Record_Plot, [xClick xClick], ...
                                      app.Record_Plot.YLim, 'Color', 'r', 'LineWidth', 2);
                    app.record_plot_clicked = true;
                else % Second Click
                    if isgraphics(app.vLine1, 'line')
                        pastX = app.vLine1.XData(1);
                        if xClick > pastX
                            app.vLine2 = line(app.Record_Plot, [xClick xClick], ...
                                              app.Record_Plot.YLim, 'Color', 'b', 'LineWidth', 2);
                            app.shadedArea = patch(app.Record_Plot, ...
                                                   'XData', [pastX, pastX, xClick, xClick], ...
                                                   'YData', [app.Record_Plot.YLim(1), app.Record_Plot.YLim(2), app.Record_Plot.YLim(2), app.Record_Plot.YLim(1)], ...
                                                   'FaceColor', [0.65, 0.65, 0.65], 'FaceAlpha', 0.3);
                            app.shadedArea.ButtonDownFcn = createCallbackFcn(app, @RecordPlotButtonDown, true);
                            app.record_plot_clicked = false; % Reset the click state
                        else
                            app.resetMarkOnRecordPlot();
                            app.vLine1 = line(app.Record_Plot, [xClick xClick], ...
                                          app.Record_Plot.YLim, 'Color', 'r', 'LineWidth', 2);
                        end
                    end
                end
            end
        end

        % Button pushed function: Record_RUNSTOPButton
        function Record_RUNSTOPButtonPushed(app, event)
            if app.isRunning % Stops updating plot
                app.resetMarkOnRecordPlot();
            end
            app.isRunning=~app.isRunning;
        end

        % Button pushed function: Record_SAVEButton
        function Record_SAVEButtonPushed(app, event)

            if isgraphics(app.vLine1, 'line') && isgraphics(app.vLine2, 'line')
                % Extract the X coordinates of the two lines
                x1 = app.vLine1.XData(1)
                x2 = app.vLine2.XData(1)
                app.Record_DispTimestampBuffer
        
                % Ensure x1 is the smaller value
                if x1 > x2
                    [x1, x2] = deal(x2, x1); % Swap x1 and x2
                end
        
                % Find the indices of the data points that fall within the range [x1, x2]
                inRangeIndices = find(app.Record_DispTimestampBuffer >= x1 & app.Record_DispTimestampBuffer <= x2);
        
                if ~isempty(inRangeIndices)
                    % Extract the data points that fall within the range
                    filteredTimestamps = app.Record_DispTimestampBuffer(inRangeIndices);
                    filteredVolts = app.Record_DispVoltBuffer(inRangeIndices);
        
                    % Call saveCSV to save the filtered data
                    app.saveCSV(filteredTimestamps, filteredVolts);
                else
                    % Handle the case where no data falls within the selected range
                    uialert(app.UIFigure, 'No data falls within the selected range.', 'Save Error');
                end
            else
                % Handle the case where one or both lines have not been placed
                uialert(app.UIFigure, 'Please select a range by placing both lines on the plot.', 'Save Error');
            end

        end

        % Button pushed function: Record_RECORDButton
        function Record_RECORDButtonPushed(app, event)
            if app.isRecording % Stop recording and save the data
                app.saveCSV(app.saveTimestampBuffer, app.saveVoltBuffer);
                app.Record_RecordLamp.Color=[0.80,0.80,0.80];
            else % Start recording
                app.Record_RecordLamp.Color='red';
      
            end
            app.isRecording=~app.isRecording;
        end

        % Value changed function: Record_VOLTSDIVKnob
        function Record_VOLTSDIVKnobValueChanged(app, event)
            app.Record_vTickSize = str2double(app.Record_VOLTSDIVKnob.Value);
            app.M_plot();
        end

        % Value changed function: Record_TIMEDIVKnob
        function Record_TIMEDIVKnobValueChanged(app, event)
            app.Record_tTickSize = str2double(app.Record_TIMEDIVKnob.Value);
            app.M_plot();
        end

        % Button pushed function: Record_LButton
        function Record_LButtonPushed(app, event)
            app.Record_tOffsetByTick=app.Record_tOffsetByTick-1;
            app.M_plot();
        end

        % Button pushed function: Record_RButton
        function Record_RButtonPushed(app, event)
            app.Record_tOffsetByTick=app.Record_tOffsetByTick+1;
            app.M_plot();
        end

        % Button pushed function: Record_UButton
        function Record_UButtonPushed(app, event)
            app.Record_vOffsetByTick=app.Record_vOffsetByTick+1;
            app.M_plot();
        end

        % Button pushed function: Record_DButton
        function Record_DButtonPushed(app, event)
            app.Record_vOffsetByTick=app.Record_vOffsetByTick-1;
            app.M_plot();
        end

        % Button pushed function: UL_SearchButton
        function UL_SearchButtonPushed(app, event)
            % Open a file open dialog allowing the user to specify a CSV file
                [fileName, path] = uigetfile({'*.csv','CSV Files (*.csv)'}, 'Select the file.');
                if isequal(fileName, 0) || isequal(path, 0)
                    disp('User clicked Cancel.');
                else
                    % Construct the full path where the file will be saved
                    fullPath = fullfile(path, fileName);
                    disp(['User selected ', fullPath, ' for playing.']);
                    
                    % Read the data from the CSV file
                    [app.UL_tBuf, app.UL_vBuf] = app.readCSV(fullPath);
            
                    % Normalize the time buffer to start from zero
                    app.UL_tBuf = app.UL_tBuf - min(app.UL_tBuf);
                    
                    % Set the axes to auto scale initially
                    app.UL_Plot.XLimMode = 'auto';
                    app.UL_Plot.YLimMode = 'auto';

                    % Plot the data
                    plot(app.UL_Plot, app.UL_tBuf, app.UL_vBuf);
            
                    % After plotting, you can set the axes to manual to lock the limits
                    % app.UL_Plot.XLimMode = 'manual';
                    % app.UL_Plot.YLimMode = 'manual';
                    
                    % Update the file edit field with the selected file name
                    app.UL_FileEditField.Value = fileName;
                end
        end

        % Button down function: UL_Plot
        function UL_PlotButtonDown(app, event)

            % Get the current x-coordinate from the click on the plot
            currentPoint = app.UL_Plot.CurrentPoint;
            curX = currentPoint(1,1);
            
            % If it's the first click
            if ~app.UL_clicked
                % Clear existing lines and shaded area if they exist
                app.UL_delComp();
                % Draw a vertical line at the first click
                app.UL_st_vline = line(app.UL_Plot, [curX, curX], ylim(app.UL_Plot), 'Color', 'red', 'LineWidth', 2);
                app.UL_clicked = true;
                app.UL_st_x=curX;
            else  % Second click
                % Get the x-coordinate of the first line
                pastX = app.UL_st_x;
                
                if curX > pastX
                    % Draw the end line and shaded area between
                    app.UL_end_vline = line(app.UL_Plot, [curX, curX], ylim(app.UL_Plot), 'Color', 'blue', 'LineWidth', 2);
                    app.UL_shaded_area = patch(app.UL_Plot, ...
                        'XData', [pastX, pastX, curX, curX], ...
                        'YData', [app.UL_Plot.YLim(1), app.UL_Plot.YLim(2), app.UL_Plot.YLim(2), app.UL_Plot.YLim(1)], ...
                        'FaceColor', 'cyan', 'FaceAlpha', 0.3);
                    
                    % Set the ButtonDownFcn for the patch
                    app.UL_shaded_area.ButtonDownFcn = createCallbackFcn(app, @UL_PlotButtonDown, true);
                    app.UL_clicked = false;
                else
                    % If the second click is not to the right of the first, redo the first line
                    app.UL_delComp();
                    app.UL_st_vline = line(app.UL_Plot, [curX, curX], ylim(app.UL_Plot), 'Color', 'red', 'LineWidth', 2);
                    app.UL_st_x=curX;
                    app.UL_clicked = true;
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PortsDropDownLabel = uilabel(app.UIFigure);
            app.PortsDropDownLabel.Text = 'Ports';
            app.PortsDropDown = uidropdown(app.UIFigure);
            app.PortsDropDown.ValueChangedFcn = createCallbackFcn(app, @PortsDropDownValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RecordTab = uitab(app.TabGroup);
            app.RecordTab.Title = 'Record';
            app.Record_Plot = uiaxes(app.RecordTab);
            zlabel(app.Record_Plot, 'Z')
            app.Record_Plot.XTickLabel = '';
            app.Record_Plot.YTickLabel = '';
            app.Record_Plot.ButtonDownFcn = createCallbackFcn(app, @Record_PlotButtonDown, true);
            app.Record_RUNSTOPButton = uibutton(app.RecordTab, 'push');
            app.Record_RUNSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @Record_RUNSTOPButtonPushed, true);
            app.Record_RUNSTOPButton.Text = 'RUN/STOP';
            app.Record_AUTOSETButton = uibutton(app.RecordTab, 'push');
            app.Record_AUTOSETButton.Text = 'AUTOSET';
            app.Record_RECORDButton = uibutton(app.RecordTab, 'push');
            app.Record_RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @Record_RECORDButtonPushed, true);
            app.Record_RECORDButton.Text = 'RECORD';
            app.Record_SAVEButton = uibutton(app.RecordTab, 'push');
            app.Record_SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @Record_SAVEButtonPushed, true);
            app.Record_SAVEButton.Text = 'SAVE';
            app.Record_RecordLamp = uilamp(app.RecordTab);
            app.MaxmVEditFieldLabel = uilabel(app.RecordTab);
            app.MaxmVEditFieldLabel.Text = 'Max [mV]';
            app.Record_MaxmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_MaxmVEditField.Editable = 'off';
            app.MinmVEditFieldLabel = uilabel(app.RecordTab);
            app.MinmVEditFieldLabel.Text = 'Min [mV]';
            app.Record_MinmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_MinmVEditField.Editable = 'off';
            app.PPAmpmVEditFieldLabel = uilabel(app.RecordTab);
            app.PPAmpmVEditFieldLabel.Text = 'P-P Amp [mV]';
            app.Record_PPAmpmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_PPAmpmVEditField.Editable = 'off';
            app.AvgmVEditFieldLabel = uilabel(app.RecordTab);
            app.AvgmVEditFieldLabel.Text = 'Avg [mV]';
            app.Record_AvgmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_AvgmVEditField.Editable = 'off';
            app.tmsEditFieldLabel = uilabel(app.RecordTab);
            app.tmsEditFieldLabel.Text = 'Δt [ms]';
            app.Record_tmsEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_tmsEditField.Editable = 'off';
            app.FreqHzEditField_2Label = uilabel(app.RecordTab);
            app.FreqHzEditField_2Label.Text = 'Freq [Hz]';
            app.Record_FreqHzEditField = uieditfield(app.RecordTab, 'numeric');
            app.Record_FreqHzEditField.Editable = 'off';
            app.Record_LButton = uibutton(app.RecordTab, 'push');
            app.Record_LButton.ButtonPushedFcn = createCallbackFcn(app, @Record_LButtonPushed, true);
            app.Record_LButton.Text = 'L';
            app.Record_RButton = uibutton(app.RecordTab, 'push');
            app.Record_RButton.ButtonPushedFcn = createCallbackFcn(app, @Record_RButtonPushed, true);
            app.Record_RButton.Text = 'R';
            app.VOLTSDIVKnobLabel = uilabel(app.RecordTab);
            app.VOLTSDIVKnobLabel.Text = 'VOLTS/DIV';
            app.Record_VOLTSDIVKnob = uiknob(app.RecordTab, 'discrete');
            app.Record_VOLTSDIVKnob.Items = {'10mV', '50mV', '100mV', '200mV', '500mV', '1V', '2V'};
            app.Record_VOLTSDIVKnob.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.Record_VOLTSDIVKnob.ValueChangedFcn = createCallbackFcn(app, @Record_VOLTSDIVKnobValueChanged, true);
            app.Record_VOLTSDIVKnob.Value = '200';
            app.TIMEDIVKnobLabel = uilabel(app.RecordTab);
            app.TIMEDIVKnobLabel.Text = 'TIME/DIV';
            app.Record_TIMEDIVKnob = uiknob(app.RecordTab, 'discrete');
            app.Record_TIMEDIVKnob.Items = {'1ms', '5ms', '10ms', '50ms', '100ms', '200ms', '500ms'};
            app.Record_TIMEDIVKnob.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.Record_TIMEDIVKnob.ValueChangedFcn = createCallbackFcn(app, @Record_TIMEDIVKnobValueChanged, true);
            app.Record_TIMEDIVKnob.Value = '10';
            app.Record_UButton = uibutton(app.RecordTab, 'push');
            app.Record_UButton.ButtonPushedFcn = createCallbackFcn(app, @Record_UButtonPushed, true);
            app.Record_UButton.Text = 'U';
            app.Record_DButton = uibutton(app.RecordTab, 'push');
            app.Record_DButton.ButtonPushedFcn = createCallbackFcn(app, @Record_DButtonPushed, true);
            app.Record_DButton.Text = 'D';
            app.UploaderTab = uitab(app.TabGroup);
            app.UploaderTab.Title = 'Uploader';
            app.UL_Plot = uiaxes(app.UploaderTab);
            zlabel(app.UL_Plot, 'Z')
            app.UL_Plot.ButtonDownFcn = createCallbackFcn(app, @UL_PlotButtonDown, true);
            app.FileEditFieldLabel = uilabel(app.UploaderTab);
            app.FileEditFieldLabel.Text = 'File';
            app.UL_FileEditField = uieditfield(app.UploaderTab, 'text');
            app.UL_FileEditField.Editable = 'off';
            app.UL_UploadButton = uibutton(app.UploaderTab, 'push');
            app.UL_UploadButton.Text = 'Upload';
            app.UL_SearchButton = uibutton(app.UploaderTab, 'push');
            app.UL_SearchButton.ButtonPushedFcn = createCallbackFcn(app, @UL_SearchButtonPushed, true);
            app.UL_SearchButton.Text = 'Search';
            app.LoopSwitchLabel = uilabel(app.UploaderTab);
            app.LoopSwitchLabel.Text = 'Loop';
            app.UL_LoopSwitch = uiswitch(app.UploaderTab, 'slider');
            app.UL_FlushButton = uibutton(app.UploaderTab, 'push');
            app.UL_FlushButton.Text = 'Flush';
            app.UL_del_vmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_del_vmVEditField.Editable = 'off';
            app.UL_del_tmsEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_del_tmsEditField.Editable = 'off';
            app.tmsLabel_2 = uilabel(app.UploaderTab);
            app.tmsLabel_2.Text = 'Δt[ms]';
            app.vmVLabel_2 = uilabel(app.UploaderTab);
            app.vmVLabel_2.Text = 'Δv[mV]';
            app.SamplingRateLabel = uilabel(app.UploaderTab);
            app.SamplingRateLabel.Text = 'Sampling Rate';
            app.UL_MaxmVEditField_2 = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MaxmVEditField_2.Editable = 'off';
            app.FsHzLabel = uilabel(app.UploaderTab);
            app.FsHzLabel.Text = 'Fs[Hz]';
            app.DurationLabel = uilabel(app.UploaderTab);
            app.DurationLabel.Text = 'Duration';
            app.AmplitudeLabel = uilabel(app.UploaderTab);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.UL_PPAmpmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_PPAmpmVEditField.Editable = 'off';
            app.UL_MinmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MinmVEditField.Editable = 'off';
            app.UL_MaxmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MaxmVEditField.Editable = 'off';
            app.UL_AvgmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_AvgmVEditField.Editable = 'off';
            app.MaxmVLabel = uilabel(app.UploaderTab);
            app.MaxmVLabel.Text = 'Max[mV]';
            app.MinmVLabel = uilabel(app.UploaderTab);
            app.MinmVLabel.Text = 'Min[mV]';
            app.AvgmVLabel = uilabel(app.UploaderTab);
            app.AvgmVLabel.Text = 'Avg[mV]';
            app.PPmVLabel = uilabel(app.UploaderTab);
            app.PPmVLabel.Text = 'P-P[mV]';
            app.IntervalLabel = uilabel(app.UploaderTab);
            app.IntervalLabel.Text = 'Interval';
            app.Playback_MaxmVEditField_2 = uieditfield(app.UploaderTab, 'numeric');
            app.Playback_MaxmVEditField_2.Editable = 'off';
            app.Playback_PPAmpmVEditField_2 = uieditfield(app.UploaderTab, 'numeric');
            app.Playback_PPAmpmVEditField_2.Editable = 'off';
            app.Playback_AvgmVEditField_2 = uieditfield(app.UploaderTab, 'numeric');
            app.Playback_AvgmVEditField_2.Editable = 'off';
            app.Playback_MinmVEditField_2 = uieditfield(app.UploaderTab, 'numeric');
            app.Playback_MinmVEditField_2.Editable = 'off';
            app.MaxmsLabel = uilabel(app.UploaderTab);
            app.MaxmsLabel.Text = 'Max[ms]';
            app.MinmsLabel = uilabel(app.UploaderTab);
            app.MinmsLabel.Text = 'Min[ms]';
            app.AvgmsLabel = uilabel(app.UploaderTab);
            app.AvgmsLabel.Text = 'Avg[ms]';
            app.StdmsLabel = uilabel(app.UploaderTab);
            app.StdmsLabel.Text = 'Std[ms]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unified_win

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end